classdef XelevateApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        OptionsMenu                 matlab.ui.container.Menu
        PreferenceMenu              matlab.ui.container.Menu
        Toolbar                     matlab.ui.container.Toolbar
        PushTool                    matlab.ui.container.toolbar.PushTool
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        GridLayout7                 matlab.ui.container.GridLayout
        ArmElevationAnglePanel      matlab.ui.container.Panel
        GridLayout5                 matlab.ui.container.GridLayout
        ThresholdAngleSpinner       matlab.ui.control.Spinner
        ThresholdAngleSpinnerLabel  matlab.ui.control.Label
        CalculateButton             matlab.ui.control.Button
        ImportIMUDataPanel          matlab.ui.container.Panel
        GridLayout6                 matlab.ui.container.GridLayout
        NodeIDPanel                 matlab.ui.container.Panel
        GridLayout8                 matlab.ui.container.GridLayout
        ThoraxDropDown              matlab.ui.control.DropDown
        ThoraxDropDownLabel         matlab.ui.control.Label
        RightHumerusDropDown        matlab.ui.control.DropDown
        RightHumerusDropDownLabel   matlab.ui.control.Label
        LeftHumerusDropDown         matlab.ui.control.DropDown
        LeftHumerusDropDownLabel    matlab.ui.control.Label
        SamplingRateDropDown        matlab.ui.control.DropDown
        SamplingRateDropDownLabel   matlab.ui.control.Label
        CalibrationDropDown         matlab.ui.control.DropDown
        CalibrationDropDownLabel    matlab.ui.control.Label
        IMUDropDown                 matlab.ui.control.DropDown
        IMUDropDownLabel            matlab.ui.control.Label
        BrowseButton                matlab.ui.control.Button
        SelectFolderEditField       matlab.ui.control.EditField
        SelectFolderEditFieldLabel  matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        GridLayout2                 matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        PlotTab                     matlab.ui.container.Tab
        GridLayout3                 matlab.ui.container.GridLayout
        PlotThresholdCheckBox_2     matlab.ui.control.CheckBox
        UIAxes                      matlab.ui.control.UIAxes
        Table                       matlab.ui.container.Tab
        GridLayout4                 matlab.ui.container.GridLayout
        ExportDataButton            matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        settings % Description
        rootDir
        
        nodeIDIdentifier
        trialDataFolder
        calibrationDataFolder
        
        % IMU Node ID
        thoraxNodeId
        rightHumerusNodeId
        leftHumerusNodeId

        % STATIC calibration data file path
        thoraxStaticFilePath
        rightHumerusStaticFilePath
        leftHumerusStaticFilePath
        
        % Trial calibration data file path
        throaxTrialFilePath
        rightHumerusTrialFilePath
        leftHumerusTrialFilePath
        
        % STATIC calibration data
        thoraxStaticData
        rightHumerusStaticData
        leftHumerusStaticData
        
        % Trial data
        thoraxTrialData
        rightHumerusTrialData
        leftHumerusTrialData
        
        
    end
    
    methods (Access = private)
        
        function StaticCalibration(app)
            
        end
        
        function CalElevationAngle(app)
            
        end
   
       
        function ReadData(app)
            opts = detectImportOptions();
        end
        
        function SetDir(app)
            dirpath = uigetdir(app.rootDir,'Select A Trial Directory');
            if dirpath == 0
                uialert(app.UIFigure, 'No directory selected.','Warning');
            else
                app.SelectFolderEditField.Value = dirpath;    
            end
        end
        
        function SetFilePath(app)
            app.throaxTrialFilePath = append(app.rootDir, );
            app.rightHumerusTrialFilePath
            app.leftHumerusTrialFilePath
            app.thoraxStaticFilePath
            app.rightHumerusStaticFilePath
            app.leftHumerusStaticFilePath
        end
    end
          

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: IMUDropDown
        function IMUDropDownValueChanged(app, event)
            value = app.IMUDropDown.Value;
            switch value
                case 'Xsens'
                    app.CalibrationDropDown.Items = {'STATIC', 'Alignment Reset'};
                case 'Anrot'
                    app.CalibrationDropDown.Items = {'STATIC', 'Anrot Reset'};
                otherwise
                    uialert(app.UIFigure, strcat(value,': currently not supported'),'Warning');
                    app.IMUDropDown.Value = 'Xsens';
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)

            SetDir(app);
           
            %fileList = dir(fullfile(dirpath, '*.txt'));
            app.CalibrationDropDown.Enable = "on";
        end

        % Menu selected function: PreferenceMenu
        function PreferenceMenuSelected(app, event)
            
        end

        % Value changed function: CalibrationDropDown
        function CalibrationDropDownValueChanged(app, event)
            value = app.CalibrationDropDown.Value;
            switch value
                case 'STATIC'
                case 'FUNCTION'
                case 'Alignment Reset'

            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {610, 610};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {317, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.PreferenceMenu = uimenu(app.OptionsMenu);
            app.PreferenceMenu.MenuSelectedFcn = createCallbackFcn(app, @PreferenceMenuSelected, true);
            app.PreferenceMenu.Text = 'Preference';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout7 = uigridlayout(app.LeftPanel);
            app.ImportIMUDataPanel = uipanel(app.GridLayout7);
            app.ImportIMUDataPanel.Title = 'Import IMU Data';
            app.GridLayout6 = uigridlayout(app.ImportIMUDataPanel);
            app.SelectFolderEditFieldLabel = uilabel(app.GridLayout6);
            app.SelectFolderEditFieldLabel.Text = 'Select Folder';
            app.SelectFolderEditField = uieditfield(app.GridLayout6, 'text');
            app.BrowseButton = uibutton(app.GridLayout6, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.IMUDropDownLabel = uilabel(app.GridLayout6);
            app.IMUDropDownLabel.Text = 'IMU';
            app.IMUDropDown = uidropdown(app.GridLayout6);
            app.IMUDropDown.Items = {'Xsens', 'Anrot'};
            app.IMUDropDown.ValueChangedFcn = createCallbackFcn(app, @IMUDropDownValueChanged, true);
            app.IMUDropDown.Tooltip = {'Select IMU provider.'};
            app.IMUDropDown.Value = 'Xsens';
            app.CalibrationDropDownLabel = uilabel(app.GridLayout6);
            app.CalibrationDropDownLabel.Enable = 'off';
            app.CalibrationDropDownLabel.Text = 'Calibration';
            app.CalibrationDropDown = uidropdown(app.GridLayout6);
            app.CalibrationDropDown.Items = {'STATIC', 'FUNCTION', 'Alignment Reset'};
            app.CalibrationDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibrationDropDownValueChanged, true);
            app.CalibrationDropDown.Enable = 'off';
            app.CalibrationDropDown.Value = 'STATIC';
            app.SamplingRateDropDownLabel = uilabel(app.GridLayout6);
            app.SamplingRateDropDownLabel.Text = 'Sampling Rate';
            app.SamplingRateDropDown = uidropdown(app.GridLayout6);
            app.SamplingRateDropDown.Items = {'100 Hz'};
            app.SamplingRateDropDown.Value = '100 Hz';
            app.NodeIDPanel = uipanel(app.GridLayout6);
            app.NodeIDPanel.Title = 'Node ID';
            app.GridLayout8 = uigridlayout(app.NodeIDPanel);
            app.LeftHumerusDropDownLabel = uilabel(app.GridLayout8);
            app.LeftHumerusDropDownLabel.Text = 'Left Humerus';
            app.LeftHumerusDropDown = uidropdown(app.GridLayout8);
            app.LeftHumerusDropDown.Items = {};
            app.LeftHumerusDropDown.Value = {};
            app.RightHumerusDropDownLabel = uilabel(app.GridLayout8);
            app.RightHumerusDropDownLabel.Text = 'Right Humerus';
            app.RightHumerusDropDown = uidropdown(app.GridLayout8);
            app.RightHumerusDropDown.Items = {};
            app.RightHumerusDropDown.Value = {};
            app.ThoraxDropDownLabel = uilabel(app.GridLayout8);
            app.ThoraxDropDownLabel.Text = 'Thorax';
            app.ThoraxDropDown = uidropdown(app.GridLayout8);
            app.ThoraxDropDown.Items = {};
            app.ThoraxDropDown.Value = {};
            app.CalculateButton = uibutton(app.GridLayout7, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Text = 'Calculate';
            app.ArmElevationAnglePanel = uipanel(app.GridLayout7);
            app.ArmElevationAnglePanel.Enable = 'off';
            app.ArmElevationAnglePanel.Title = 'Arm Elevation Angle';
            app.GridLayout5 = uigridlayout(app.ArmElevationAnglePanel);
            app.ThresholdAngleSpinnerLabel = uilabel(app.GridLayout5);
            app.ThresholdAngleSpinnerLabel.Text = 'Threshold Angle';
            app.ThresholdAngleSpinner = uispinner(app.GridLayout5);
            app.ThresholdAngleSpinner.Value = 90;
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.GridLayout3 = uigridlayout(app.PlotTab);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Shoulder Elevation Angle')
            xlabel(app.UIAxes, 'Sample Number ')
            ylabel(app.UIAxes, 'Elevation Angle (Degree)')
            zlabel(app.UIAxes, 'Z')
            app.PlotThresholdCheckBox_2 = uicheckbox(app.GridLayout3);
            app.PlotThresholdCheckBox_2.Text = 'Plot Threshold';
            app.PlotThresholdCheckBox_2.Value = true;
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.GridLayout4 = uigridlayout(app.Table);
            app.UITable = uitable(app.GridLayout4);
            app.UITable.ColumnName = {'R_SHOULDER_ELEVATION'; 'L_SHOULDER_ELEVATION'};
            app.UITable.RowName = {};
            app.ExportDataButton = uibutton(app.GridLayout4, 'push');
            app.ExportDataButton.Text = 'Export Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XelevateApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end