classdef XelevateAppDemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        OptionsMenu              matlab.ui.container.Menu
        Toolbar                  matlab.ui.container.Toolbar
        PushTool                 matlab.ui.container.toolbar.PushTool
        GridLayout               matlab.ui.container.GridLayout
        LeftPanel                matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        CalculateButton          matlab.ui.control.Button
        SettingsPanel            matlab.ui.container.Panel
        GridLayout4              matlab.ui.container.GridLayout
        ThoraxButton             matlab.ui.control.Button
        LShoulderButton          matlab.ui.control.Button
        RShoulderButton          matlab.ui.control.Button
        AngleSpinner             matlab.ui.control.Spinner
        AngleSpinnerLabel        matlab.ui.control.Label
        ThoraxEditField          matlab.ui.control.EditField
        ThoraxEditFieldLabel     matlab.ui.control.Label
        LShoulderEditField       matlab.ui.control.EditField
        LShoulderEditFieldLabel  matlab.ui.control.Label
        RShoulderEditField       matlab.ui.control.EditField
        RShoulderEditFieldLabel  matlab.ui.control.Label
        RightPanel               matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        StatisticsLabel          matlab.ui.control.Label
        SaveDataButton           matlab.ui.control.Button
        UIAnglePlot              matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        workingdirectory
        
        
        % Trial data file path
        thoraxTrialFilePath
        RShoulderFilePath
        LShoulderFilePath
        
        RAngle
        LAngle
        thresholdAngle

        quatCol = ["Quat_q0", "Quat_q1", "Quat_q2", "Quat_q3"];
    end
    
    methods (Access = private)
        
        function filepath = getFile(app)
            [file,path] = uigetfile("*.txt", 'Select a File');
            if isequal(file,0)
                uialert(app.UIFigure, 'No file selected.','Warning');
                filepath = "";
            else
                filepath = append(path, file);
            end
        end
        
        function table = getTable(~, filepath)
            opts = detectImportOptions(filepath);
            table = readtable(filepath, opts);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RShoulderButton
        function RShoulderButtonPushed(app, event)
            app.RShoulderEditField.Value = app.getFile();
        end

        % Button pushed function: LShoulderButton
        function LShoulderButtonPushed(app, event)
            app.LShoulderEditField.Value = app.getFile();
        end

        % Button pushed function: ThoraxButton
        function ThoraxButtonPushed(app, event)
            app.ThoraxEditField.Value = app.getFile();
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            try
                thoraxTable = app.getTable(app.ThoraxEditField.Value);
                LShoulderTable = app.getTable(app.LShoulderEditField.Value);
                RShoulderTable = app.getTable(app.RShoulderEditField.Value);
                q1 = thoraxTable{:, app.quatCol};
                q2 = RShoulderTable{:, app.quatCol};
                q3 =  LShoulderTable{:, app.quatCol};
                qR = quaternion(quatmultiply(quatconj(q2), q1));
                qL = quaternion(quatmultiply(quatconj(q3), q1));
                mR = quat2rotm(qR);
                mL = quat2rotm(qL);
                aR = rad2deg(acos(reshape(mR(3,3,:),[], 1)));
                aL = rad2deg(acos(reshape(mL(3,3,:),[], 1)));
                tR = sum(aR >= app.thresholdAngle) * 0.01;
                tL = sum(aL >= app.thresholdAngle) * 0.01;
                tT = numel(aR) * 0.01;
                pR = (tR/tT) * 100;
                pL = (tL/tT) * 100;
                app.StatisticsLabel.Text = ["Statistics:", ...
                                            "Total Time (s): " + tT, ...
                                            "Time over " + app.thresholdAngle + char(176) + " (s): R = " + tR + " ; L = " + tL, ...
                                            "Percentage of time over " + app.thresholdAngle + char(176) + " (%): R = " + pR + " ; L = " + pL];

                plot(app.UIAnglePlot, aR, 'DisplayName', 'Right Arm');
                hold(app.UIAnglePlot);
                plot(app.UIAnglePlot, aL, 'DisplayName', 'Left Arm');
                hold(app.UIAnglePlot);
                yline(app.UIAnglePlot, app.thresholdAngle,'-.r', 'DisplayName', 'Threshold');
                legend(app.UIAnglePlot);
                app.RAngle = aR;
                app.LAngle = aL;

            catch ME
                message = sprintf('Error in calculation:\n%s', ME.message);
                uiwait(warndlg(message));
            end
        end

        % Value changed function: AngleSpinner
        function AngleSpinnerValueChanged(app, event)
            value = app.AngleSpinner.Value;
            app.thresholdAngle = value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {561, 561};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.OptionsMenu = uimenu(app.UIFigure);
            app.OptionsMenu.Text = 'Options';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.SettingsPanel = uipanel(app.GridLayout2);
            app.SettingsPanel.Title = 'Settings';
            app.GridLayout4 = uigridlayout(app.SettingsPanel);
            app.RShoulderEditFieldLabel = uilabel(app.GridLayout4);
            app.RShoulderEditFieldLabel.Text = 'R Shoulder';
            app.RShoulderEditField = uieditfield(app.GridLayout4, 'text');
            app.LShoulderEditFieldLabel = uilabel(app.GridLayout4);
            app.LShoulderEditFieldLabel.Text = 'L Shoulder';
            app.LShoulderEditField = uieditfield(app.GridLayout4, 'text');
            app.ThoraxEditFieldLabel = uilabel(app.GridLayout4);
            app.ThoraxEditFieldLabel.Text = 'Thorax';
            app.ThoraxEditField = uieditfield(app.GridLayout4, 'text');
            app.AngleSpinnerLabel = uilabel(app.GridLayout4);
            app.AngleSpinnerLabel.Text = 'Angle';
            app.AngleSpinner = uispinner(app.GridLayout4);
            app.AngleSpinner.ValueChangedFcn = createCallbackFcn(app, @AngleSpinnerValueChanged, true);
            app.RShoulderButton = uibutton(app.GridLayout4, 'push');
            app.RShoulderButton.ButtonPushedFcn = createCallbackFcn(app, @RShoulderButtonPushed, true);
            app.RShoulderButton.Icon = fullfile(pathToMLAPP, 'folder.png');
            app.RShoulderButton.Text = '';
            app.LShoulderButton = uibutton(app.GridLayout4, 'push');
            app.LShoulderButton.ButtonPushedFcn = createCallbackFcn(app, @LShoulderButtonPushed, true);
            app.LShoulderButton.Icon = fullfile(pathToMLAPP, 'folder.png');
            app.LShoulderButton.Text = '';
            app.ThoraxButton = uibutton(app.GridLayout4, 'push');
            app.ThoraxButton.ButtonPushedFcn = createCallbackFcn(app, @ThoraxButtonPushed, true);
            app.ThoraxButton.Icon = fullfile(pathToMLAPP, 'folder.png');
            app.ThoraxButton.Text = '';
            app.CalculateButton = uibutton(app.GridLayout2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAnglePlot = uiaxes(app.GridLayout3);
            title(app.UIAnglePlot, 'Arm Elevation Angle')
            xlabel(app.UIAnglePlot, 'Time (s)')
            ylabel(app.UIAnglePlot, 'Elevation Angle (Degree)')
            zlabel(app.UIAnglePlot, 'Z')
            app.SaveDataButton = uibutton(app.GridLayout3, 'push');
            app.SaveDataButton.Text = 'Save Data';
            app.StatisticsLabel = uilabel(app.GridLayout3);
            app.StatisticsLabel.Text = 'Statistics:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = XelevateAppDemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end